classdef DC_predictor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCViewer                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        FirstLoadmodelLabel       matlab.ui.control.Label
        LoadmodelButton           matlab.ui.control.Button
        dt                        matlab.ui.control.NumericEditField
        CustomdtsLabel            matlab.ui.control.Label
        duration                  matlab.ui.control.Knob
        start                     matlab.ui.control.Knob
        ToSlider                  matlab.ui.control.Slider
        ToLabel                   matlab.ui.control.Label
        FromSlider                matlab.ui.control.Slider
        FromSliderLabel           matlab.ui.control.Label
        xmax                      matlab.ui.control.NumericEditField
        xmaxEditFieldLabel        matlab.ui.control.Label
        xmin                      matlab.ui.control.NumericEditField
        xminEditFieldLabel        matlab.ui.control.Label
        HowmanyseriesButtonGroup  matlab.ui.container.ButtonGroup
        ThreePlot                 matlab.ui.control.RadioButton
        TwoPlot                   matlab.ui.control.RadioButton
        OnePlot                   matlab.ui.control.RadioButton
        Notes                     matlab.ui.control.EditField
        DecimatetoEditField       matlab.ui.control.NumericEditField
        DecimatetoEditFieldLabel  matlab.ui.control.Label
        LoadfileButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        extraAxis                 matlab.ui.control.UIAxes
        idealAxis                 matlab.ui.control.UIAxes
        rawAxis                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        decim % Level of decimation. Default =1 = no decimation!
        ADDTIME % Do we need to add a time column? true or false
        FILELOADED
        newscale
        maxtime
        minr
        maxr
        curfile
        modelLOADED
        model
        
        
    end
    
    properties (Access = public)
        data %the main dataset
        si %the datas sample interval; public version of dt. should always match!!
    end
    
    methods (Access = private)
        
        function results = plot1ch(app)
            app.data =[linspace(0,length(data(:,1)),length(data))',data(:,1)];
            app.maxtime=max(data(:,1));
            app.xmin.Value=min(data(:,1));
            app.xmax.Value=max(data(:,1));          
            plot(app.rawAxis,data(:,1),data(:,2));
            app.rawAxis.YLim=[0,max(data(:,2)*1.25)];
            results=0;
        end
       
        
        function plot2ch(app)  
            %start by preparing to plot
            if app.ADDTIME==true
                addtime(app);
            end
            app.maxtime=max(app.data(:,1));
            %%Todo decimate it
            factor=app.decim;
            %do the actual plot
            [minr, maxr, YLim]=fullplot(app);  
            app.xmin.Value=minr;
            app.xmax.Value=maxr;  
            %legacy bug. would do nothing?
            app.idealAxis.YLim=YLim;   
        end
        
        function addtime(app)
            app.data =[infertime(app),app.data(:,1:end)];
        end
        
        function results = setaxis(app,minr,maxr)
            app.rawAxis.XLim=[minr, maxr];
            app.idealAxis.XLim=[minr, maxr];
            app.extraAxis.XLim=[minr, maxr];           
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.newscale=false;
            app.si=app.dt.Value;
            app.ADDTIME=false;
            app.FromSlider.Value=0;
            app.ToSlider.Value=100;
            app.xmin.Value=0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DCViewer.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {584, 584};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            startupFcn(app);
            [FileName,PathName] = uigetfile({'*.txt;*.csv'},'File Selector');
            if ((FileName==0) | (app.modelLOADED ==false))
                app.FILELOADED=false;
                return
            end        
            
            app.curfile=fullfile(PathName,FileName);

            if verLessThan('matlab', '9.6')
                app.data = csvread(app.curfile);
            else
                app.data = readmatrix(app.curfile);
            end
            
            app.FILELOADED=true;
            app.Notes.Value=string(FileName);
            val=istime(app);
            if(val>0)
                
                %keeping public and private version which sounds wrong :-)
                app.si=val;
                app.dt.Value=val;              
            else
                addtime(app);
            end
            app.data=double(pyrunfile("MLpredictorTF2.py","c", mymodel=app.model,  data=app.data));
            dcols=length(app.data(1,:));
            if dcols==2
                app.OnePlot.Value=true;
            else
                app.OnePlot.Value=false;
            end
            %This should be unecessary now...
            val=istime(app);
            app.FILELOADED=true;
            app.Notes.Value=string(FileName);
            val=istime(app);
            if(val>0)
                app.ADDTIME=false;
                %eeping public and private version which sounds wrong :-)
                app.si=val;
                app.dt.Value=val;              
            else
                app.ADDTIME=true;
            end
            
            if app.OnePlot.Value==true
                %Todo: Not really sorted this yet.
                plot1ch(app);
            else 
                plot2ch(app);
            end
            
        end

        % Value changed function: DecimatetoEditField
        function DecimatetoEditFieldValueChanged(app, event)
            app.decim = app.DecimatetoEditField.Value;
            
        end

        % Callback function
        function IsthereatimecolButtonGroupSelectionChanged(app, event)
            selectedButton = app.IsthereatimecolButtonGroup.SelectedObject;
                if app.YesTime==true
                    app.NoTime=false;
                    app.ADDTIME=false;
                end
                if app.NoTime==true
                    app.YesTime=false;
                    app.ADDTIME=true;
                end
        end

        % Value changed function: xmin
        function xminValueChanged(app, event)
                if app.newscale==true
                    maxX=app.xmax.Value;
                else
                    maxX=app.rawAxis.XLim(2);
                end
            value = app.xmin.Value;
                if value >maxX
                    value=0;
                end
            if app.xmin.Value>app.xmax.Value
                app.xmin.Value=0;
                app.FromSlider.Value=0;               
            end 
            setaxis(app, value, maxX);  
            
            app.FromSlider.Value=100*value/app.maxtime;
            app.newscale=true;
        end

        % Value changed function: xmax
        function xmaxValueChanged(app, event)
           if app.newscale==true
                minX=app.xmin.Value;
            else
                minX=app.rawAxis.XLim(1);
                app.rawAxis.XLim;
            end
            value = app.xmax.Value;
            if value<minX
                value=app.maxtime;
            end
            if value>app.maxtime
                value=app.maxtime;
            end
            setaxis(app, minX, value);

            app.ToSlider.Value=100*value/app.maxtime;
            app.newscale=true;
            
        end

        % Value changed function: FromSlider
        function FromSliderValueChanged(app, event)
            value = app.FromSlider.Value;
            if app.newscale==true
                maxX=app.xmax.Value;
            else
                maxX=app.rawAxis.XLim(2);
            end
            
            app.xmin.Value=value*app.maxtime/100;
            
            if app.xmin.Value>app.xmax.Value
                app.xmin.Value=0;
                app.FromSlider.Value=0;               
            end
            setaxis(app,app.xmin.Value, maxX);    
            app.newscale=true;
            
        end

        % Value changed function: ToSlider
        function ToSliderValueChanged(app, event)
            value = app.ToSlider.Value;
            if app.newscale==true
                minX=app.xmin.Value;
            else
                minX=app.rawAxis.XLim(1);
            end
            
            app.xmax.Value=value*app.maxtime/100;
            if app.xmax.Value<app.xmin.Value
                app.xmax.Value=app.maxtime;
                app.ToSlider.Value=100;
                
            end
            setaxis(app,minX, app.xmax.Value)  
            app.newscale=true;
            
        end

        % Value changed function: dt
        function dtValueChanged(app, event)
            value = app.dt.Value;
            app.si=value;  
        end

        % Value changed function: duration
        function durationValueChanged(app, event)
            windowlen = app.duration.Value;
            if app.FILELOADED==true
                %convert to datapoints
                windowlen=windowlen*app.maxtime/100;
                startpt=app.start.Value*app.maxtime/100;
                if (startpt+windowlen)>app.maxtime
                    val=100*(app.maxtime-startpt)/app.maxtime;
                    if val>100
                        val=100;
                    end
                    app.duration.Value=val;
                end
                setaxis(app,startpt,startpt+windowlen);
                app.xmin.Value=startpt;
                app.xmax.Value=startpt+windowlen;
                val=100*startpt/app.maxtime;
                if val<0
                    val=0;
                end
                app.FromSlider.Value=val;
                val = 100*(startpt+windowlen)/app.maxtime;
                if val>100
                    val=100;
                end
                app.ToSlider.Value=val;
                app.newscale=true;
            else
                app.duration.Value=100;
            end
            
        end

        % Value changed function: start
        function startValueChanged(app, event)
            value = app.start.Value;   
            if app.FILELOADED==true
                %convert to datapoints
                windowlen=app.duration.Value*app.maxtime/100;
                startpt=value*app.maxtime/100;               
                if (startpt+windowlen) > app.maxtime
                    val=100*(app.maxtime-windowlen)/app.maxtime;
                    if val<0
                        val=0;
                    end
                app.start.Value=val;
                end
                setaxis(app,startpt, startpt+windowlen )
  
                app.xmin.Value=startpt;
                app.xmax.Value=startpt+windowlen;
                val=100*startpt/app.maxtime;
                if val<0
                    val=0;
                end
                app.FromSlider.Value=val;
                val = 100*(startpt+windowlen)/app.maxtime;
                if val>100
                    val=100;
                end
                app.ToSlider.Value=val;
                app.newscale=true;
            else
                app.start.Value=0;
            end
        end

        % Button pushed function: LoadmodelButton
        function LoadmodelButtonPushed(app, event)
            [FileName,PathName] = uigetfile({'*.h5'},'File Selector');
            if FileName==0
                app.modelLOADED=false;
                return
            end        
            
            app.model=fullfile(PathName,FileName);
            app.modelLOADED=true;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCViewer = uifigure('Visible', 'off');
            app.DCViewer.Name = 'DC Viewer';
            app.DCViewer.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DCViewer);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Get Files';
            app.LoadfileButton = uibutton(app.LeftPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.DecimatetoEditFieldLabel = uilabel(app.LeftPanel);
            app.DecimatetoEditFieldLabel.Text = 'Decimate to';
            app.DecimatetoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DecimatetoEditField.ValueChangedFcn = createCallbackFcn(app, @DecimatetoEditFieldValueChanged, true);
            app.DecimatetoEditField.Value = 1;
            app.Notes = uieditfield(app.LeftPanel, 'text');
            app.Notes.Value = 'Nothing';
            app.HowmanyseriesButtonGroup = uibuttongroup(app.LeftPanel);
            app.HowmanyseriesButtonGroup.Title = 'How many series?';
            app.OnePlot = uiradiobutton(app.HowmanyseriesButtonGroup);
            app.OnePlot.Text = ' One';
            app.TwoPlot = uiradiobutton(app.HowmanyseriesButtonGroup);
            app.TwoPlot.Text = 'Two';
            app.TwoPlot.Value = true;
            app.ThreePlot = uiradiobutton(app.HowmanyseriesButtonGroup);
            app.ThreePlot.Text = 'Three';
            app.xminEditFieldLabel = uilabel(app.LeftPanel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xmin = uieditfield(app.LeftPanel, 'numeric');
            app.xmin.Limits = [0 Inf];
            app.xmin.ValueChangedFcn = createCallbackFcn(app, @xminValueChanged, true);
            app.xmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmax = uieditfield(app.LeftPanel, 'numeric');
            app.xmax.Limits = [0 Inf];
            app.xmax.ValueChangedFcn = createCallbackFcn(app, @xmaxValueChanged, true);
            app.FromSliderLabel = uilabel(app.LeftPanel);
            app.FromSliderLabel.Text = 'From';
            app.FromSlider = uislider(app.LeftPanel);
            app.FromSlider.ValueChangedFcn = createCallbackFcn(app, @FromSliderValueChanged, true);
            app.ToLabel = uilabel(app.LeftPanel);
            app.ToLabel.Text = 'To';
            app.ToSlider = uislider(app.LeftPanel);
            app.ToSlider.ValueChangedFcn = createCallbackFcn(app, @ToSliderValueChanged, true);
            app.ToSlider.Value = 100;
            app.start = uiknob(app.LeftPanel, 'continuous');
            app.start.ValueChangedFcn = createCallbackFcn(app, @startValueChanged, true);
            app.start.Tooltip = {'start value for scrolling'};
            app.duration = uiknob(app.LeftPanel, 'continuous');
            app.duration.ValueChangedFcn = createCallbackFcn(app, @durationValueChanged, true);
            app.duration.Tooltip = {'length of scroll window'};
            app.duration.Value = 100;
            app.CustomdtsLabel = uilabel(app.LeftPanel);
            app.CustomdtsLabel.Text = 'Custom dt (s)';
            app.dt = uieditfield(app.LeftPanel, 'numeric');
            app.dt.ValueChangedFcn = createCallbackFcn(app, @dtValueChanged, true);
            app.dt.Value = 1e-05;
            app.LoadmodelButton = uibutton(app.LeftPanel, 'push');
            app.LoadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmodelButtonPushed, true);
            app.LoadmodelButton.Text = 'Load model';
            app.FirstLoadmodelLabel = uilabel(app.LeftPanel);
            app.FirstLoadmodelLabel.Text = 'First: Load model';
            app.RightPanel = uipanel(app.GridLayout);
            app.rawAxis = uiaxes(app.RightPanel);
            title(app.rawAxis, 'Typically Raw')
            ylabel(app.rawAxis, 'Y')
            app.idealAxis = uiaxes(app.RightPanel);
            title(app.idealAxis, 'Typically ideal')
            ylabel(app.idealAxis, 'Y')
            app.extraAxis = uiaxes(app.RightPanel);
            title(app.extraAxis, 'Extra Axis')
            xlabel(app.extraAxis, 'time (s)')
            ylabel(app.extraAxis, 'Y')
            % Show the figure after all components are created
            app.DCViewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DC_predictor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCViewer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCViewer)
        end
    end
end